classdef GUI_Scripts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        CaracterizacinPCMPAMporSNRqTab  matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        SincronizacindeSimbolosPanel    matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        CalculodeNivelesPanel           matlab.ui.container.Panel
        ObtenerNivelesyBitsporSimboloButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SNRqdBSpinnerLabel              matlab.ui.control.Label
        SNRqdBSpinner                   matlab.ui.control.Spinner
        InserteelSNRdecuantizacinLabel  matlab.ui.control.Label
        SealdeVozPanel                  matlab.ui.container.Panel
        A1SpinnerLabel                  matlab.ui.control.Label
        A1Spinner                       matlab.ui.control.Spinner
        A2SpinnerLabel                  matlab.ui.control.Label
        A2Spinner                       matlab.ui.control.Spinner
        A3SpinnerLabel                  matlab.ui.control.Label
        A3Spinner                       matlab.ui.control.Spinner
        A4SpinnerLabel                  matlab.ui.control.Label
        A4Spinner                       matlab.ui.control.Spinner
        CalcularSealdeVozyParametrosButton  matlab.ui.control.Button
        f1SpinnerLabel                  matlab.ui.control.Label
        f1Spinner                       matlab.ui.control.Spinner
        f2SpinnerLabel                  matlab.ui.control.Label
        f2Spinner                       matlab.ui.control.Spinner
        f3SpinnerLabel                  matlab.ui.control.Label
        f3Spinner                       matlab.ui.control.Spinner
        f4SpinnerLabel                  matlab.ui.control.Label
        f4Spinner                       matlab.ui.control.Spinner
        ParmetrosCaracteristicosPanel   matlab.ui.container.Panel
        RbbitssegundoSpinnerLabel       matlab.ui.control.Label
        RbbitssegundoSpinner            matlab.ui.control.Spinner
        BpcmHzSpinnerLabel              matlab.ui.control.Label
        BpcmHzSpinner                   matlab.ui.control.Spinner
        MPAMRsbaudiosSpinnerLabel       matlab.ui.control.Label
        MPAMRsbaudiosSpinner            matlab.ui.control.Spinner
        BpamHzSpinnerLabel              matlab.ui.control.Label
        BpamHzSpinner                   matlab.ui.control.Spinner
        EntropiaBitsCaracterSpinnerLabel  matlab.ui.control.Label
        EntropiaBitsCaracterSpinner     matlab.ui.control.Spinner
        EntropianatsSpinnerLabel        matlab.ui.control.Label
        EntropianatsSpinner             matlab.ui.control.Spinner
        TabGroup2                       matlab.ui.container.TabGroup
        SealAnalgicaTab                 matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        CuantificacinTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        BitStreamTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        UIAxes2_2                       matlab.ui.control.UIAxes
        TramaGeneradaEditFieldLabel     matlab.ui.control.Label
        TramaGeneradaEditField          matlab.ui.control.EditField
        stymtTab                        matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        DominioFrecuencialTab           matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        BPFyDataClockTab                matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes9_2                       matlab.ui.control.UIAxes
        UIAxes9_3                       matlab.ui.control.UIAxes
        FrecuenciadeSincronizacinSpinnerLabel  matlab.ui.control.Label
        FrecuenciadeSincronizacinSpinner  matlab.ui.control.Spinner
        SincronizarButton               matlab.ui.control.Button
        LongituddeTramaSpinnerLabel     matlab.ui.control.Label
        LongituddeTramaSpinner          matlab.ui.control.Spinner
        nSpinnerLabel                   matlab.ui.control.Label
        nSpinner                        matlab.ui.control.Spinner
        SealEstimadaTab                 matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        Image2                          matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function EjecutarButtonPushed(app, event)
            app.TabGroup.Visible = 'off';
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % Nodes Configurate
            node = app.Tree.SelectedNodes.Text
            % State Panels
            homepage = [app.Image.Visible];
            optionMenu = ["🏠 Pagina Principal" "Parametrización por SNRq"];
            optionMenu(2)
            strcmp(node, optionMenu(2))
            menuStatus = [app.SincronizacindeSimbolosPanel.Visible];
            
            if strcmp(node, optionMenu(1)) == 1
                app.SincronizacindeSimbolosPanel.Visible="off";
            end
            if strcmp(node, optionMenu(2)) == 1
               app.SincronizacindeSimbolosPanel.Visible="on";
%                 pcm_pam_script
            end
        end

        % Button pushed function: 
        % ObtenerNivelesyBitsporSimboloButton
        function ObtenerNivelesyBitsporSimboloButtonPushed(app, event)

             [n L] = calculateSNR(app.SNRqdBSpinner.Value);
             app.Label.Text = strcat('L = ',int2str(L),' Niveles')
             app.Label_2.Text = strcat('n = ',int2str(n),' [bits/PCM word]')
          
        end

        % Button pushed function: CalcularSealdeVozyParametrosButton
        function CalcularSealdeVozyParametrosButtonPushed(app, event)
            close all
            A1 = app.A1Spinner.Value;
            A2 = app.A2Spinner.Value;
            A3 = app.A3Spinner.Value;
            A4 = app.A4Spinner.Value;
            
            f1 = app.f1Spinner.Value;
            f2 = app.f2Spinner.Value;
            f3 = app.f3Spinner.Value;
            f4 = app.f4Spinner.Value;
            
            [n L] = calculateSNR(app.SNRqdBSpinner.Value);
            app.Label.Text = strcat('L = ',int2str(L),' Niveles');
            app.Label_2.Text = strcat('n = ',int2str(n),' [bits/PCM word]');
            
            [t F R_b B_pcm R_s B_pam quatizedSignal tb ...
                f P1 t_pam PAM tagsDec valoresCuatificacion ... 
                tagsBin trama pcm_r f_pam P1_pam trama_pcm tb2 mt f_Mt Mt BPF f_sincronia] = signalVoice(A1,A2,A3,A4,f1,f2,f3,f4,n,L)
            plotSignalVoice = plot(app.UIAxes,t, F);
            plotSignalVoice.LineWidth = 1.5;
            plotSignalVoice = 'red';
           
            
            app.RbbitssegundoSpinner.Value = R_b;
            app.BpcmHzSpinner.Value = B_pcm;
            app.MPAMRsbaudiosSpinner.Value = R_s;
            app.BpamHzSpinner.Value = B_pam;


            % Coded Signal

            plot(app.UIAxes2,t,F,t,quatizedSignal, 'LineWidth',1.5);
            app.UIAxes2.YTick = valoresCuatificacion;
            app.UIAxes2.YTickLabel = tagsBin;
            tags={};
            for i=1:1:length(tagsBin) 
                tags{i} = num2str(tagsBin{i});
            end
            
            a = [flip(num2cell(valoresCuatificacion')) flip(tags')];
            app.UITable.Data = a;
            
            % Digital Data
            
            plotDigital = plot(app.UIAxes2_2,tb,trama);
                plotDigital.LineWidth = 1.5;
                plotDigital.Color='#0D00EB';
                app.UIAxes2_2.YLim = [-2 2];
                app.UIAxes2_2.XLim = [0 tb(1000)];
                
                valTrama = ''
                for i=1:1:length(pcm_r)
                    valTrama = strcat(valTrama,pcm_r(i,:),'-');
                end
             app.TramaGeneradaEditField.Value = valTrama
            
            
            % PCM Signal - Frequency Domain
            plotDigitalFrequency = plot(app.UIAxes3,f,P1);
                app.UIAxes3.XLim = [0 36e3];
                app.UIAxes3.YLim = [0 0.5];
                
            %% Graphics PAM
            
            %PAM signal
            
%             plotPAM = plot(app.UIAxes4,f_pam, P1_pam,'LineWidth',1.5);
            total = length(trama_pcm)
            Pceros = length(find(trama_pcm==0))/total;
            Punos = length(find(trama_pcm==1))/total;
            entropia = length(find(trama_pcm==0))*Pceros*log2(1/Pceros)+...
                length(find(trama_pcm==1))*Punos*log2(1/Punos);
            app.EntropiaBitsCaracterSpinner.Value = entropia;
            entropia_nats = entropia*log(2);
            app.EntropianatsSpinner.Value = entropia_nats;
            
            %%-------------------------
            plotDigital = plot(app.UIAxes7,tb,trama);
                plotDigital.LineWidth = 1.5;
                plotDigital.Color='#0D00EB';
                app.UIAxes7.YLim = [-1 2];
                app.UIAxes7.XLim = [0 tb(1000)];
           
            plotmt = plot(app.UIAxes7_2,tb2,mt);
                plotmt.LineWidth = 1.5;
                plotmt.Color='#0D00EB';
                app.UIAxes7_2.YLim = [-1 2];
                app.UIAxes7_2.XLim = [0 tb(1000)];
                
            plotmtFreq = plot(app.UIAxes4,f_Mt,Mt);
                plotmtFreq.Color='#0D00EB';
                app.UIAxes4.XLim = [0 36e3];
                app.UIAxes4.YLim = [0 0.3];
            
            plotBPF = plot(app.UIAxes8,f_Mt,BPF);
                plotBPF.Color='#0D00EB';
                app.UIAxes8.XLim = [0 36e3];
                app.UIAxes8.YLim = [0 0.3];
            
            plotTrama = plot(app.UIAxes9_2,tb,trama);
                plotTrama.Color='#0D00EB';
                app.UIAxes9_2.XLim = [0 tb(500)];
                app.UIAxes9_2.YLim = [-2 2];
                
            app.FrecuenciadeSincronizacinSpinner.Value = f_sincronia
            app.LongituddeTramaSpinner.Value = length(trama)
            app.nSpinner.Value = n;
        end

        % Callback function
        function CalcularParmetrosButtonPushed(app, event)
            fm = app.FrecuenciafmEditField.Value;
            A1 =app.VppEditField.Value/2;
            L = 1/(2*app.ErrordeCuantizacinEditField.Value);
            if L==0 || L < 0
                alert('Fuera del Rango Establecido')
            elseif L > 0 && L<=1
                L=1;
            elseif L>1 && L<=2
                L=2;
            elseif L>2 && L<=4
                L=4;
            elseif L>4 && L<=8
                L=8;
            elseif L>8 && L<=16
                L=16;
            elseif L>16 && L<=32
                L=32;
            elseif L>32 && L<=64
                L=64;
            elseif L>64 && L<=128
                L=128;
            elseif L>128 && L<=256
                L=256;
            end
            n = log(L)/log(2);
            M = app.MPAMEditField.Value;
            
           [t F R_b B_pcm R_s B_pam quatizedSignal tb ...
                f P1 t_pam PAM tagsDec valoresCuatificacion ... 
                tagsBin trama pcm_r f_pam P1_pam trama_pcm tb2 mt f_Mt Mt BPF f_sincronia] = signalVoice(A1,A2,A3,A4,f1,f2,f3,f4,n,L)
            
            app.RbbitssegundoSpinner_2.Value = R_b;
            app.BpcmHzSpinner_2.Value = B_pcm;
            app.MPAMRsbaudiosSpinner_2.Value = R_s;
            app.BpamHzSpinner_2.Value = B_pam;
            
            total = length(trama_pcm);
            Pceros = length(find(trama_pcm==0))/total;
            Punos = length(find(trama_pcm==1))/total;
            entropia = length(find(trama_pcm==0))*Pceros*log2(1/Pceros)+...
                length(find(trama_pcm==1))*Punos*log2(1/Punos);
            app.EntropiaBitsCaracterSpinner_2.Value = entropia;
            entropia_nats = entropia*log(2);
            app.EntropianatsSpinner_2.Value = entropia_nats;
            
            % Coded Signal

            plot(app.UIAxes5,t,F,t,quatizedSignal, 'LineWidth',1.5);
            app.UIAxes5.YTick = valoresCuatificacion;
            app.UIAxes5.YTickLabel = tagsBin;
            tags={};
            for i=1:1:length(tagsBin) 
                tags{i} = num2str(tagsBin{i});
            end
            
            a = [flip(num2cell(valoresCuatificacion')) flip(tags')];
            app.UITable2.Data = a;
            
             valTramaPCM = '';
             tramaCompleta = '';
             for i=1:1:length(pcm_r)
                    tramaCompleta = strcat(tramaCompleta,pcm_r(i,:));
                    valTramaPCM = strcat(valTramaPCM,pcm_r(i,:),'-');
             end
             k = log(M)/log(2);
             
             for i=1:length(tramaCompleta)
                 if mod(length(tramaCompleta),k)==0
                     i = length(tramaCompleta);
                 else
                     tramaCompleta(end) = []
                 end
             end
             
             pam_r = reshape(tramaCompleta,k,[])';
             valTramaPAM = '';
             for i=1:1:length(pcm_r)
                    valTramaPAM = strcat(valTramaPAM,pam_r(i,:),'-');
             end
             
             app.TramaPCMEditField_2.Value = valTramaPCM;
             app.TramaPAMEditField.Value = valTramaPAM;
             app.nbitsPCMwordSpinner.Value = n;
             app.kbitssimbolosSpinner.Value = k;
        end

        % Callback function
        function CalculodePotenciasButtonPushed(app, event)
            mu = app.MediaSpinner.Value;
            desviacion = app.DesviacinEstandarSpinner.Value;
            [x y_norm potenciaDC potenciaAC potenciaTotal entropia]=potenciaRuido(mu,desviacion);
            app.PotenciaContinuaSpinner.Value = potenciaDC;
            app.PotenciaAltenaSpinner.Value =potenciaAC;
            app.PotenciaTotalSpinner.Value = potenciaTotal;
            app.EntropiaSpinner_3.Value = entropia;
            
            plot(app.UIAxes6,x,y_norm);
        end

        % Button pushed function: SincronizarButton
        function SincronizarButtonPushed(app, event)
            
             A1 = app.A1Spinner.Value;
            A2 = app.A2Spinner.Value;
            A3 = app.A3Spinner.Value;
            A4 = app.A4Spinner.Value;
            
            f1 = app.f1Spinner.Value;
            f2 = app.f2Spinner.Value;
            f3 = app.f3Spinner.Value;
            f4 = app.f4Spinner.Value;
            
            [n L] = calculateSNR(app.SNRqdBSpinner.Value);
          
            [t F R_b B_pcm R_s B_pam quatizedSignal tb ...
                f P1 t_pam PAM tagsDec valoresCuatificacion ... 
                tagsBin trama pcm_r f_pam P1_pam trama_pcm tb2 mt f_Mt Mt BPF f_sincronia ts tm numPeriodos] = signalVoice(A1,A2,A3,A4,f1,f2,f3,f4,n,L)
            
            f_sincronia = app.FrecuenciadeSincronizacinSpinner.Value;
            lenTrama = app.LongituddeTramaSpinner.Value;
            n=app.nSpinner.Value;
            [t_clock clock samples t m_estimada] = receptor(f_sincronia,lenTrama,n,trama,valoresCuatificacion,ts,tm,numPeriodos);
            
            plotclock = plot(app.UIAxes9,t_clock,clock)
                plotclock.Color='#0D00EB';
                app.UIAxes9.YLim = [-2 2];
                app.UIAxes9.XLim = [0 t_clock(500)];
                
            plotsync = plot(app.UIAxes9_3,t_clock,samples)
                plotsync.Color='#0D00EB';
                app.UIAxes9_3.YLim = [-2 2];
                app.UIAxes9_3.XLim = [0 t_clock(500)];
           
            plotestimado = plot(app.UIAxes10,t,m_estimada)
                plotestimado.Color='#0D00EB';
                app.UIAxes10.YTick = valoresCuatificacion;
               app.UIAxes10.YTickLabel = tagsBin;
                tags={};
                for i=1:1:length(tagsBin) 
                    tags{i} = num2str(tagsBin{i});
                end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.CaracterizacinPCMPAMporSNRqTab = uitab(app.TabGroup);
            app.CaracterizacinPCMPAMporSNRqTab.Title = '1️⃣ Caracterización PCM-PAM por SNRq';
            app.GridLayout9 = uigridlayout(app.CaracterizacinPCMPAMporSNRqTab);
            app.SincronizacindeSimbolosPanel = uipanel(app.GridLayout9);
            app.SincronizacindeSimbolosPanel.Title = 'Sincronización de Simbolos';
            app.GridLayout10 = uigridlayout(app.SincronizacindeSimbolosPanel);
            app.CalculodeNivelesPanel = uipanel(app.GridLayout10);
            app.CalculodeNivelesPanel.Title = 'Calculo de Niveles';
            app.ObtenerNivelesyBitsporSimboloButton = uibutton(app.CalculodeNivelesPanel, 'push');
            app.ObtenerNivelesyBitsporSimboloButton.ButtonPushedFcn = createCallbackFcn(app, @ObtenerNivelesyBitsporSimboloButtonPushed, true);
            app.ObtenerNivelesyBitsporSimboloButton.Text = 'Obtener Niveles y Bits por Simbolo';
            app.Label = uilabel(app.CalculodeNivelesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.CalculodeNivelesPanel);
            app.Label_2.Text = {''; ''};
            app.SNRqdBSpinnerLabel = uilabel(app.CalculodeNivelesPanel);
            app.SNRqdBSpinnerLabel.Text = 'SNRq (dB)';
            app.SNRqdBSpinner = uispinner(app.CalculodeNivelesPanel);
            app.SNRqdBSpinner.Value = 30;
            app.InserteelSNRdecuantizacinLabel = uilabel(app.CalculodeNivelesPanel);
            app.InserteelSNRdecuantizacinLabel.Text = 'Inserte el SNR de cuantización';
            app.SealdeVozPanel = uipanel(app.GridLayout10);
            app.SealdeVozPanel.Title = 'Señal de Voz';
            app.A1SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A1SpinnerLabel.Text = 'A1';
            app.A1Spinner = uispinner(app.SealdeVozPanel);
            app.A1Spinner.Value = 3;
            app.A2SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A2SpinnerLabel.Text = 'A2';
            app.A2Spinner = uispinner(app.SealdeVozPanel);
            app.A2Spinner.Value = 5;
            app.A3SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A3SpinnerLabel.Text = 'A3';
            app.A3Spinner = uispinner(app.SealdeVozPanel);
            app.A3Spinner.Value = 1;
            app.A4SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A4SpinnerLabel.Text = 'A4';
            app.A4Spinner = uispinner(app.SealdeVozPanel);
            app.A4Spinner.Value = 1.5;
            app.CalcularSealdeVozyParametrosButton = uibutton(app.SealdeVozPanel, 'push');
            app.CalcularSealdeVozyParametrosButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularSealdeVozyParametrosButtonPushed, true);
            app.CalcularSealdeVozyParametrosButton.Text = 'Calcular Señal de Voz y Parametros';
            app.f1SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f1SpinnerLabel.Text = 'f1';
            app.f1Spinner = uispinner(app.SealdeVozPanel);
            app.f1Spinner.Value = 4000;
            app.f2SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f2SpinnerLabel.Text = 'f2';
            app.f2Spinner = uispinner(app.SealdeVozPanel);
            app.f2Spinner.Value = 3300;
            app.f3SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f3SpinnerLabel.Text = 'f3';
            app.f3Spinner = uispinner(app.SealdeVozPanel);
            app.f3Spinner.Value = 2400;
            app.f4SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f4SpinnerLabel.Text = 'f4';
            app.f4Spinner = uispinner(app.SealdeVozPanel);
            app.f4Spinner.Value = 1500;
            app.ParmetrosCaracteristicosPanel = uipanel(app.GridLayout10);
            app.ParmetrosCaracteristicosPanel.Title = 'Parámetros Caracteristicos';
            app.RbbitssegundoSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.RbbitssegundoSpinnerLabel.Text = 'Rb [bits/segundo]';
            app.RbbitssegundoSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.BpcmHzSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.BpcmHzSpinnerLabel.Text = 'Bpcm [Hz]';
            app.BpcmHzSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.MPAMRsbaudiosSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.MPAMRsbaudiosSpinnerLabel.Text = 'M-PAM Rs [baudios]';
            app.MPAMRsbaudiosSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.BpamHzSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.BpamHzSpinnerLabel.Text = 'Bpam [Hz]';
            app.BpamHzSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.EntropiaBitsCaracterSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.EntropiaBitsCaracterSpinnerLabel.Text = 'Entropia [Bits/Caracter]';
            app.EntropiaBitsCaracterSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.EntropianatsSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.EntropianatsSpinnerLabel.Text = 'Entropia [nats]';
            app.EntropianatsSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.SealAnalgicaTab = uitab(app.TabGroup2);
            app.SealAnalgicaTab.Title = 'Señal Analógica';
            app.GridLayout4 = uigridlayout(app.SealAnalgicaTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Señal de Voz')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'Nivel de Voltaje [V]')
            app.CuantificacinTab = uitab(app.TabGroup2);
            app.CuantificacinTab.Title = 'Cuantificación';
            app.GridLayout5 = uigridlayout(app.CuantificacinTab);
            app.UIAxes2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2, 'Señal Cuantificada')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'Nivel de Voltage [V]')
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = {'Voltaje'; 'Simbolo'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.BitStreamTab = uitab(app.TabGroup2);
            app.BitStreamTab.Title = 'Bit Stream';
            app.GridLayout6 = uigridlayout(app.BitStreamTab);
            app.UIAxes2_2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2_2, 'Señal Codificada / Señal Digital')
            xlabel(app.UIAxes2_2, 't [s]')
            ylabel(app.UIAxes2_2, 'Valor de Bit')
            app.TramaGeneradaEditFieldLabel = uilabel(app.GridLayout6);
            app.TramaGeneradaEditFieldLabel.Text = 'Trama Generada';
            app.TramaGeneradaEditField = uieditfield(app.GridLayout6, 'text');
            app.stymtTab = uitab(app.TabGroup2);
            app.stymtTab.Title = 's(t) y m(t)';
            app.UIAxes7 = uiaxes(app.stymtTab);
            title(app.UIAxes7, 'Señal s(t)')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes7_2 = uiaxes(app.stymtTab);
            title(app.UIAxes7_2, 'Señal m(t)')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            app.DominioFrecuencialTab = uitab(app.TabGroup2);
            app.DominioFrecuencialTab.Title = 'Dominio Frecuencial';
            app.GridLayout7 = uigridlayout(app.DominioFrecuencialTab);
            app.UIAxes3 = uiaxes(app.GridLayout7);
            title(app.UIAxes3, 'S(f) - Dominio de la Frecuencia')
            xlabel(app.UIAxes3, 'Voltage [V]')
            ylabel(app.UIAxes3, 'frecuencia [Hz]')
            app.UIAxes4 = uiaxes(app.GridLayout7);
            title(app.UIAxes4, 'M(f) - Dominio de la Frecuencia')
            xlabel(app.UIAxes4, 'Frecuencia [Hz]')
            ylabel(app.UIAxes4, 'Voltage [V]')
            app.BPFyDataClockTab = uitab(app.TabGroup2);
            app.BPFyDataClockTab.Title = 'BPF y Data Clock';
            app.UIAxes8 = uiaxes(app.BPFyDataClockTab);
            title(app.UIAxes8, 'Señal BPF')
            xlabel(app.UIAxes8, 'f [Hz]')
            ylabel(app.UIAxes8, 'V [Volts]')
            app.UIAxes9 = uiaxes(app.BPFyDataClockTab);
            title(app.UIAxes9, 'Señal de Reloj (Sincronizado)')
            xlabel(app.UIAxes9, 't [s]')
            ylabel(app.UIAxes9, 'Volts')
            app.UIAxes9_2 = uiaxes(app.BPFyDataClockTab);
            title(app.UIAxes9_2, 'Trama de bits s(t)')
            xlabel(app.UIAxes9_2, 't')
            ylabel(app.UIAxes9_2, 'Volts')
            app.UIAxes9_3 = uiaxes(app.BPFyDataClockTab);
            title(app.UIAxes9_3, 'Muestreo yi')
            xlabel(app.UIAxes9_3, 't')
            ylabel(app.UIAxes9_3, 'Volts')
            app.FrecuenciadeSincronizacinSpinnerLabel = uilabel(app.BPFyDataClockTab);
            app.FrecuenciadeSincronizacinSpinnerLabel.Text = 'Frecuencia de Sincronización';
            app.FrecuenciadeSincronizacinSpinner = uispinner(app.BPFyDataClockTab);
            app.SincronizarButton = uibutton(app.BPFyDataClockTab, 'push');
            app.SincronizarButton.ButtonPushedFcn = createCallbackFcn(app, @SincronizarButtonPushed, true);
            app.SincronizarButton.Text = 'Sincronizar';
            app.LongituddeTramaSpinnerLabel = uilabel(app.BPFyDataClockTab);
            app.LongituddeTramaSpinnerLabel.Text = 'Longitud de Trama';
            app.LongituddeTramaSpinner = uispinner(app.BPFyDataClockTab);
            app.nSpinnerLabel = uilabel(app.BPFyDataClockTab);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.BPFyDataClockTab);
            app.SealEstimadaTab = uitab(app.TabGroup2);
            app.SealEstimadaTab.Title = 'Señal Estimada';
            app.UIAxes10 = uiaxes(app.SealEstimadaTab);
            title(app.UIAxes10, 'Señal Estimada en el Receptor')
            xlabel(app.UIAxes10, 't [s]')
            ylabel(app.UIAxes10, 'Volts')
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'Bit_Depth.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Scripts

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end